classdef HDD_controllerDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HDDservosystemUIFigure          matlab.ui.Figure
        EditField                       matlab.ui.control.EditField
        GeneratePlantModelButton        matlab.ui.control.Button
        ClassiccontrollerButton         matlab.ui.control.Button
        LQGcontrollerButton             matlab.ui.control.Button
        HinfinitecontrollerButton       matlab.ui.control.Button
        mucontrollerButton              matlab.ui.control.Button
        Figures                         matlab.ui.container.TabGroup
        Plant                           matlab.ui.container.Tab
        Gmag_fig                        matlab.ui.control.UIAxes
        Gphase_fig                      matlab.ui.control.UIAxes
        pzGu                            matlab.ui.container.Tab
        pzG_fig                         matlab.ui.control.UIAxes
        r2e_nom                         matlab.ui.container.Tab
        r2e_fig                         matlab.ui.control.UIAxes
        SettlingTime5EditFieldLabel     matlab.ui.control.Label
        SettlingTime                    matlab.ui.control.NumericEditField
        OvershootEditFieldLabel         matlab.ui.control.Label
        Overshoot                       matlab.ui.control.NumericEditField
        controllertypeEditFieldLabel    matlab.ui.control.Label
        controllertypeEditField         matlab.ui.control.EditField
        d2e_nom                         matlab.ui.container.Tab
        d2e_fig                         matlab.ui.control.UIAxes
        controllertypeEditField_2Label  matlab.ui.control.Label
        controllertypeEditField_2       matlab.ui.control.EditField
        r2u_nom                         matlab.ui.container.Tab
        r2u_fig                         matlab.ui.control.UIAxes
        u_maxEditFieldLabel             matlab.ui.control.Label
        u_max                           matlab.ui.control.NumericEditField
        controllertypeEditField_3Label  matlab.ui.control.Label
        controllertypeEditField_3       matlab.ui.control.EditField
        d2u_nom                         matlab.ui.container.Tab
        d2u_fig                         matlab.ui.control.UIAxes
        controllertypeEditField_4Label  matlab.ui.control.Label
        controllertypeEditField_4       matlab.ui.control.EditField
        r2e_pert                        matlab.ui.container.Tab
        r2e_pert_fig                    matlab.ui.control.UIAxes
        Overshoot_pertLabel             matlab.ui.control.Label
        Overshoot_pert                  matlab.ui.control.NumericEditField
        SettlingTime_pert5Label         matlab.ui.control.Label
        SettlingTime_pert               matlab.ui.control.NumericEditField
        controllertypeEditField_5Label  matlab.ui.control.Label
        controllertypeEditField_5       matlab.ui.control.EditField
        d2e_pert                        matlab.ui.container.Tab
        d2e_pert_fig                    matlab.ui.control.UIAxes
        controllertypeEditField_6Label  matlab.ui.control.Label
        controllertypeEditField_6       matlab.ui.control.EditField
        r2u_pert                        matlab.ui.container.Tab
        r2u_pert_fig                    matlab.ui.control.UIAxes
        u_max_pertLabel                 matlab.ui.control.Label
        u_max_pert                      matlab.ui.control.NumericEditField
        controllertypeEditField_7Label  matlab.ui.control.Label
        controllertypeEditField_7       matlab.ui.control.EditField
        d2u_pert                        matlab.ui.container.Tab
        d2u_pert_fig                    matlab.ui.control.UIAxes
        controllertypeEditField_8Label  matlab.ui.control.Label
        controllertypeEditField_8       matlab.ui.control.EditField
        OL_bode                         matlab.ui.container.Tab
        OL_bode_fig                     matlab.ui.control.UIAxes
        OL_phase_fig                    matlab.ui.control.UIAxes
        GainMargin                      matlab.ui.control.NumericEditField
        PhaseMargin                     matlab.ui.control.NumericEditField
        gainat100HzdBLabel              matlab.ui.control.Label
        gainat100Hz                     matlab.ui.control.NumericEditField
        controllertypeEditField_9Label  matlab.ui.control.Label
        controllertypeEditField_9       matlab.ui.control.EditField
        GMdBLabel                       matlab.ui.control.Label
        PMLabel                         matlab.ui.control.Label
        CL_bode                         matlab.ui.container.Tab
        CL_bode_fig                     matlab.ui.control.UIAxes
        CL_phase_fig                    matlab.ui.control.UIAxes
        CLBandwidthEditFieldLabel       matlab.ui.control.Label
        CLBandwidth                     matlab.ui.control.NumericEditField
        CLpeakEditFieldLabel            matlab.ui.control.Label
        CLpeak                          matlab.ui.control.NumericEditField
        controllertypeEditField_10Label  matlab.ui.control.Label
        controllertypeEditField_10      matlab.ui.control.EditField
        Controller_bodeplot             matlab.ui.container.Tab
        K_bode_fig                      matlab.ui.control.UIAxes
        K_phase_fig                     matlab.ui.control.UIAxes
        controllertypeEditField_11Label  matlab.ui.control.Label
        controllertypeEditField_11      matlab.ui.control.EditField
        K_pz                            matlab.ui.container.Tab
        K_pz_fig                        matlab.ui.control.UIAxes
        controllertypeEditField_12Label  matlab.ui.control.Label
        controllertypeEditField_12      matlab.ui.control.EditField
        K_step                          matlab.ui.container.Tab
        K_step_fig                      matlab.ui.control.UIAxes
        controllertypeEditField_13Label  matlab.ui.control.Label
        controllertypeEditField_13      matlab.ui.control.EditField
        NP                              matlab.ui.container.Tab
        NP_comp_fig                     matlab.ui.control.UIAxes
        RS                              matlab.ui.container.Tab
        RS_comp_fig                     matlab.ui.control.UIAxes
        RP                              matlab.ui.container.Tab
        RP_comp_fig                     matlab.ui.control.UIAxes
        RScomparisonButton              matlab.ui.control.Button
        RPcomparisonButton              matlab.ui.control.Button
        NPcomparisonButton              matlab.ui.control.Button
        JKgm2Label                      matlab.ui.control.Label
        J_slider                        matlab.ui.control.Slider
        ktNmALabel                      matlab.ui.control.Label
        kt_slider                       matlab.ui.control.Slider
        kyVtrackLabel                   matlab.ui.control.Label
        ky_slider                       matlab.ui.control.Slider
        Jpert                           matlab.ui.control.NumericEditField
        ktpert                          matlab.ui.control.NumericEditField
        kypert                          matlab.ui.control.NumericEditField
        WorstRSperturbationButton       matlab.ui.control.Button
        WorstRPperturbationButton       matlab.ui.control.Button
        LTRcontrollerButton             matlab.ui.control.Button
    end


    properties (Access = private)
        
        
        G % Uncertain transfer matrix from [td,u] to y
        P % Uncertain general plant (uncertain transfer matrix from [r,td,n,u] to [ey,eu,v]
        
        Kcl % classic controller
        Klqg % LQG controller
        Kltr % LTR controller
        Kinf % H infinite controller
        Pmu % Uncertain general plant used for mu synthesis(that is with every parameter fixed except J,kt,ks)
        Kmu  % mu controller
        
        Kev % controller being evalueted
        
        Pnom % nominal general plant (nominal transfer matrix from [r,td,n,u] to [ey,eu,v]
        
        MaxSingValue_cl % max singular value of closed loop matrix with classic controller
        MaxSingValue_lqg % max singular value of closed loop matrix with lqg controller
        MaxSingValue_ltr % max singular value of closed loop matrix with ltr controller
        MaxSingValue_hinf % max singular value of closed loop matrix with H infinite controller       
        MaxSingValue_mu % max singular value of closed loop matrix with mu controller
        
        mu_RS_cl % structural singular value upper bound of M-Delta structure with classic controller 
        mu_RS_lqg % structural singular value upper bound of M-Delta structure with lqg controller 
        mu_RS_ltr % structural singular value upper bound of M-Delta structure with ltr controller
        mu_RS_hinf  % structural singular value upper bound of M-Delta structure with H infinite controller        
        mu_RS_mu % structural singular value upper bound of M-Delta structure with mu controller 
        
        mu_RP_cl % structural singular value upper bound of N-DeltaAugmented structure with classic controller
        mu_RP_lqg % structural singular value upper bound of N-DeltaAugmented structure with lqg controller
        mu_RP_ltr % structural singular value upper bound of N-DeltaAugmented structure with ltr controller
        mu_RP_hinf  % structural singular value upper bound of N-DeltaAugmented structure with H infinite controller      
        mu_RP_mu % structural singular value upper bound of N-DeltaAugmented structure with mu controller
        
        is_enable_hinf % 
        is_enable_mu   %
        GM % gain margin
        PM % phase margin
        
        J_p % arm inertia in kg/m^2          
        kt_p % VCM torque constant in N*m/A
        ky_p % position measurement gain in V/track
        
        rel_pert_J % relative perturbation on J
        rel_pert_kt % relative perturbation on kt
        rel_pert_ky % relative perturbation on ky
        
        Tfinal % final time of simulations
        
        worst_mu_RS % worst case perturbation for stability with mu controller
        worst_mu_RP % worst case perturbation for performance with mu controller
        
        worst_inf_RS % worst case perturbation for stability with H infinite controller
        worst_inf_RP % worst case perturbation for performance with H infinite controller
        controllerNumber % 1=classic; 2=LQG; 3=H inf; 4=mu
        
    end

    methods (Access = private)
    
        function save_in_workspace(~,var,default_name)
            %var_name = inputdlg('Name of the destination variable', 'Save in workspace', 1, {default_name});
            assignin('base', default_name, var);
        end
        
        function mu_RS=RobustStability(~,GenPlant,Contr) 
          % computes max singular value of N(closed loop matrix from [r,td,n] to [ey,eu]); upper bound 
          %structured singular value of M-Delta structure for RS analysis and upper bound structred
          %singular value of N-DeltaAugmented structure for RP analysis

          om=logspace(2,5,30);         
          N_unc=lft(GenPlant,Contr);     % closed loop uncertain transfer matrix          
            
          % Robust Stability
          [M,Delta,blk]=lftdata(N_unc);  % creates M-Delta structure to analyze robust stability with structured singular value
           M=frd(M,om);
          n=length(Delta);  % 11 uncertain parameters, 4 of which repetead twice(the resonant mode's frequencies)--> 15x15 DELTA matrix
          mu_RS=mussv(M(1:n,1:n),blk);   % structured singular value for RS analysis
          mu_RS=mu_RS(:,1);


        end        
 
        function  figGenerate(app,K)
            
            % Transient response to step references and step disturbances, control
            % action, open loop bode plot and complementary sensitivity bode plot of
            % nominal plant controlled by selected controller
            
               Lnom=K*app.G.nom(1,2);              % nominal open loop transfer function from r to y
               Snom=inv(1+K*app.G.nom(1,2));    % nominal sensibility function(tf from d to y)
               Sd2PESnom=-0.0005*app.G.nom(1,1)*Snom;  % nominal tf from disturbance( 0.0005Nm amplitude step) to PES
               Tnom=1-Snom;                          % nominal complementary sensibility function(tf from r to y)
               Sunom=K*Snom;       % nominal control sensibility function(from r to u)
               Sudnom=0.0005*K*Snom*app.G.nom(1,1);  % nominal transfer function from disturbance( 0.0005Nm amplitude step) to u
 
               %Tfinal=5e-3;
               cla(app.r2e_fig)
               [y,t]=step(Snom);
               plot(app.r2e_fig,t,y)
               grid(app.r2e_fig, 'on')
               app.r2e_fig.XLim = [0, t(end)];
               app.Tfinal=t(end);
               
               Step_nom=stepinfo(Tnom,'SettlingTimeThreshold',0.05);
               app.SettlingTime.Value=Step_nom.SettlingTime;
               app.Overshoot.Value=Step_nom.Overshoot;
               
               
               cla(app.d2e_fig)
               [y,t]=step(Sd2PESnom);
               plot(app.d2e_fig,t,y)
               grid(app.d2e_fig, 'on')
               app.d2e_fig.XLim = [0, t(end)];
                                             
               cla(app.r2u_fig)
               [y,t]=step(Sunom);
               plot(app.r2u_fig,t,y)
               grid(app.r2u_fig, 'on')
               app.r2u_fig.XLim = [0, t(end)];
               
               app.u_max.Value=max(abs(y));
               
               cla(app.d2u_fig)
               [y,t]=step(Sudnom);
               plot(app.d2u_fig,t,y)
               grid(app.d2u_fig, 'on')
               app.d2u_fig.XLim = [0, t(end)];
   
               % perturbed system
               
               %J_pert=app.J_p.nom*(1+app.rel_pert_J*0.1);  % J perturbed value
               %kt_pert=app.kt_p.nom*(1+app.rel_pert_kt*0.1); % kt perturbed value
               %ky_pert=app.ky_p.nom*(1+app.rel_pert_ky*0.1);  % ky perturbed value
               %G_pert=usubs(app.G, 'J',J_pert,'kt',kt_pert,'ky',ky_pert);
               
                         % perturbed transfer functions
               
               %Lpert=K*G_pert(1,2);   % perturbed open loop transfer function from r to y
               %Spert=(1+Lpert)^-1;      % perturbed sensibility function(tf from d to y)
               %Sd2PESpert=-0.0005*G_pert(1,1)*Spert;  % perturbed tf from disturbance( 0.0005Nm amplitude step) to PES
               %Tpert=1-Spert;             % perturbed complementary sensibility function(tf from r to y)
               %Supert=K*Spert;     % perturbed control sensibility function(from r to u)
               %Sudpert=0.0005*K*Spert*G_pert(1,1);  % perturbed transfer function 
                                             % from disturbance( 0.0005Nm amplitude step) to u
 
               %cla(app.r2e_pert_fig)              
               %[y,t]=step(Spert,t(end));
               %plot(app.r2e_pert_fig,t,y) 
               %grid(app.r2e_pert_fig, 'on')
               %app.r2e_pert_fig.XLim = [0, t(end)];
               %           
               %cla(app.d2e_pert_fig)              
               %[y,t]=step(Sd2PESpert,t(end));
               %plot(app.d2e_pert_fig,t,y) 
               %grid(app.d2e_pert_fig, 'on')
               %app.d2e_pert_fig.XLim = [0, t(end)];
              % 
              % cla(app.r2u_pert_fig)              
               %[y,t]=step(Supert,t(end));
               %plot(app.r2u_pert_fig,t,y) 
               %grid(app.r2u_pert_fig, 'on')
               %app.r2u_pert_fig.XLim = [0, t(end)];
               
               %cla(app.d2u_pert_fig)              
               %[y,t]=step(Sudpert,t(end));
               %plot(app.d2u_pert_fig,t,y) 
               %grid(app.d2u_pert_fig, 'on')
               %app.d2u_pert_fig.XLim = [0, t(end)];
               
                             % Uncertain transfer functions
                             
               %Lunc=K*app.G(1,2);   % uncertain open loop transfer function from r to y
               %Sunc=(1+Lunc)^-1;      % uncertain sensibility function(tf from d to y)
               %Sd2PESunc=-0.0005*app.G(1,1)*Sunc;  % uncertain tf from disturbance( 0.0005Nm amplitude step) to PES
               %Tunc=1-Sunc;             % uncertain complementary sensibility function(tf from r to y)
               %Suunc=K*Sunc;     % uncertain control sensibility function(from r to u)
               %Sudunc=0.0005*K*Sunc*app.G(1,1);  % uncertain transfer function 
                                             % from disturbance( 0.0005Nm amplitude step) to u


               %dd= usample(Sunc,30);
               %cla(app.r2e_pert_fig)
               %hold(app.r2e_pert_fig,'on')
               %for i=1:30
               %[y,t]=step(dd(:,:,i,1),t(end));
               %hold on
               %plot(app.r2e_pert_fig,t,y)
               %end
               %hold(app.r2e_pert_fig,'off')
               %grid(app.r2e_pert_fig, 'on')
               %app.r2e_pert_fig.XLim = [0, t(end)];
 
               %dd=usample(Sd2PESunc,30);
               %cla(app.d2e_pert_fig)
               %hold(app.d2e_pert_fig,'on')
               %for i=1:30
               %[y,t]=step(dd(:,:,i,1),t(end));
               %hold on
               %plot(app.d2e_pert_fig,t,y)
               %end
               %hold(app.d2e_pert_fig,'off')
               %grid(app.d2e_pert_fig, 'on')
               %app.d2e_pert_fig.XLim = [0, t(end)];

               %dd=usample(Suunc,30);
               %cla(app.r2u_pert_fig)
               %hold(app.r2u_pert_fig,'on')
               %for i=1:30
               %[y,t]=step(dd(:,:,i,1),t(end));
               %hold on
               %plot(app.r2u_pert_fig,t,y)
               %end
               %hold(app.r2u_pert_fig,'off')
               %grid(app.r2u_pert_fig, 'on')
               %app.r2u_pert_fig.XLim = [0, t(end)];
               
               %dd=usample(Sudunc,30);
               %cla(app.d2u_pert_fig)
               %hold(app.d2u_pert_fig,'on')
               %for i=1:30
               %[y,t]=step(dd(:,:,i,1),t(end));
               %hold on
               %plot(app.d2u_pert_fig,t,y)
               %end
               %hold(app.d2u_pert_fig,'off')
               %grid(app.d2u_pert_fig, 'on')
               %app.d2u_pert_fig.XLim = [0, t(end)];
               
               w=logspace(2,5,100);
               
               cla(app.OL_bode_fig)
               cla(app.OL_phase_fig)
               hold(app.OL_bode_fig,'on')
               hold(app.OL_phase_fig,'on')               
               [m,f]=bode(Lnom,w);
               plot(app.OL_bode_fig,w,20*log10(m(1,:)))               
               plot(app.OL_phase_fig,w,f(1,:)) 
               app.OL_bode_fig.XScale='log';
               app.OL_phase_fig.XScale='log';
               hold(app.OL_bode_fig,'off')
               hold(app.OL_phase_fig,'off')
               grid(app.OL_bode_fig, 'on')
               grid(app.OL_phase_fig, 'on')
               close
                 
               [app.GM,app.PM]=margin(Lnom);
               app.GainMargin.Value=20*log10(app.GM);              
               app.PhaseMargin.Value=app.PM;   
               [m,~]=bode(Lnom,100*2*pi);
               app.gainat100Hz.Value=20*log10(m);
               
               cla(app.CL_bode_fig)
               cla(app.CL_phase_fig)
               hold(app.CL_bode_fig,'on')
               hold(app.CL_phase_fig,'on')               
               [m,f]=bode(Tnom,w);
               plot(app.CL_bode_fig,w,20*log10(m(1,:)))               
               plot(app.CL_phase_fig,w,f(1,:)) 
               app.CL_bode_fig.XScale='log';
               app.CL_phase_fig.XScale='log';
               hold(app.CL_bode_fig,'off')
               hold(app.CL_phase_fig,'off')
               grid(app.CL_bode_fig, 'on')
               grid(app.CL_phase_fig, 'on')
               close
 
               app.CLpeak.Value=max(m(1,:));               
               app.CLBandwidth.Value=bandwidth(Tnom);
               
               wc=logspace(-1,7,500);
               cla(app.K_bode_fig)
               cla(app.K_phase_fig)
               hold(app.K_bode_fig,'on')
               hold(app.K_phase_fig,'on')               
               [m,f]=bode(K,wc);
               plot(app.K_bode_fig,wc,20*log10(m(1,:)))               
               plot(app.K_phase_fig,wc,f(1,:)) 
               app.K_bode_fig.XScale='log';
               app.K_phase_fig.XScale='log';
               hold(app.K_bode_fig,'off')
               hold(app.K_phase_fig,'off')
               grid(app.K_bode_fig, 'on')
               grid(app.K_phase_fig, 'on')
               close
               
               cla(app.K_pz_fig)
               [z,p,~] = zpkdata(K,'v');
               hold(app.K_pz_fig,'on')
               scatter(app.K_pz_fig,real(p),imag(p),'x')
               hold on
               scatter(app.K_pz_fig,real(z),imag(z),'o')
               hold(app.K_pz_fig,'off')
               grid(app.K_pz_fig, 'on')
               close
               
               cla(app.K_step_fig)
               [y,t]=step(K);
               plot(app.K_step_fig,t,y)
               grid(app.K_step_fig, 'on')
               app.K_step_fig.XLim = [0, t(end)];
               
               
        end
        
        function mu_RP = RobustPerformance(~,GenPlant,Contr)
            
             %  computes upper bound structred singular value of N-DeltaAugmented
             %  structure for Robust Performance analysis
             
             om=logspace(1,5,100);
             N=lft(GenPlant.nom,Contr);  % closed loop nominal transfer matrix
             N_unc=lft(GenPlant,Contr); 
             DeltaP=ucomplexm('DeltaP',zeros(3,2));  % creates a fictitious uncertainty block with compatible dimension with closed loop transfer matrix
             N_RP=lft(N_unc,DeltaP);             
             [M_RP,Delta,blk]=lftdata(N_RP);   % creates the structure for robust performace analysis with structured singular value
             M_RPfrd=frd(M_RP,om);
             sz=size(Delta);
             mu_RP=mussv(M_RPfrd(1:sz(2),1:sz(1)),blk);   % structured singular value for RP analysis
             mu_RP=mu_RP(:,1);
        end
        
        function  MSValue = NominalPerformance(~,GenPlant,Contr)
             om=logspace(1,5,100);
             N=lft(GenPlant.nom,Contr);  % closed loop nominal transfer matrix
             
             % Nominal Stability
             NS=isstable(N);  % return 1 if system is internally stable(every transfer function in N is stable)

             % Nominal Performance

             sv=sigma(N,om);
             sv=frd(sv(1,:),om);
             MSValue=sv(1,:);
            
        end
        
        function controlbuttonEnable(app, is_enable)
            if is_enable
              app.ClassiccontrollerButton.Enable = 'on';
              app.LQGcontrollerButton.Enable = 'on';
              app.LTRcontrollerButton.Enable = 'on';
              app.HinfinitecontrollerButton.Enable = 'on';
              app.mucontrollerButton.Enable = 'on';
            else
              app.ClassiccontrollerButton.Enable = 'off';
              app.LQGcontrollerButton.Enable = 'off';
              app.LTRcontrollerButton.Enable = 'off';
              app.HinfinitecontrollerButton.Enable = 'off';
              app.mucontrollerButton.Enable = 'off';
            end
              
        end
        
        function  comparisonbuttonEnable(app, is_enable_hinf,is_enable_mu)
            if is_enable_hinf && is_enable_mu
              app.NPcomparisonButton.Enable = 'on';
              app.RScomparisonButton.Enable = 'on';
              app.RPcomparisonButton.Enable = 'on';
            else
              app.NPcomparisonButton.Enable = 'off';
              app.RScomparisonButton.Enable = 'off';
              app.RPcomparisonButton.Enable = 'off';  
            end
        end
        
        function  pert_fig(app,K)
            
            % perturbed system
               
               J_pert=app.J_p.nom*(1+app.rel_pert_J*1e-2*app.J_p.percentage(1,2));  % J perturbed value
               kt_pert=app.kt_p.nom*(1+app.rel_pert_kt*0.1);  % kt perturbed value
               ky_pert=app.ky_p.nom*(1+app.rel_pert_ky*0.05);  % ky perturbed value
               G_pert=usubs(app.G, 'J',J_pert,'kt',kt_pert,'ky',ky_pert);
               
               %app.Jpert.Value=J_pert;
               %app.ktpert.Value=kt_pert;
               %app.kypert.Value=ky_pert;
               
            % perturbed transfer functions
               
               Lpert=K*G_pert(1,2);   % perturbed open loop transfer function from r to y
               Spert=(1+Lpert)^-1;      % perturbed sensibility function(tf from d to y)
               Sd2PESpert=-0.0005*G_pert(1,1)*Spert;  % perturbed tf from disturbance( 0.0005Nm amplitude step) to PES
               Tpert=1-Spert;             % perturbed complementary sensibility function(tf from r to y)
               Supert=K*Spert;     % perturbed control sensibility function(from r to u)
               Sudpert=0.0005*K*Spert*G_pert(1,1);  % perturbed transfer function 
                                             % from disturbance( 0.0005Nm amplitude step) to u
 
               cla(app.r2e_pert_fig)              
               [y,t]=step(Spert,app.Tfinal);
               plot(app.r2e_pert_fig,t,y) 
               grid(app.r2e_pert_fig, 'on')
               app.r2e_pert_fig.XLim = [0, t(end)];
                
               Step_pert=stepinfo(Tpert,'SettlingTimeThreshold',0.05);
               app.SettlingTime_pert.Value=Step_pert.SettlingTime;
               app.Overshoot_pert.Value=Step_pert.Overshoot;
               
               cla(app.d2e_pert_fig)              
               [y,t]=step(Sd2PESpert,app.Tfinal);
               plot(app.d2e_pert_fig,t,y) 
               grid(app.d2e_pert_fig, 'on')
               app.d2e_pert_fig.XLim = [0, t(end)];
               
               cla(app.r2u_pert_fig)              
               [y,t]=step(Supert,app.Tfinal);
               plot(app.r2u_pert_fig,t,y) 
               grid(app.r2u_pert_fig, 'on')
               app.r2u_pert_fig.XLim = [0, t(end)];
               
               app.u_max_pert.Value=max(abs(y));
               
               cla(app.d2u_pert_fig)              
               [y,t]=step(Sudpert,app.Tfinal);
               plot(app.d2u_pert_fig,t,y) 
               grid(app.d2u_pert_fig, 'on')
               app.d2u_pert_fig.XLim = [0, t(end)];
        end
        
        function clear_pertFig(app)
              cla(app.r2e_pert_fig)
              cla(app.d2e_pert_fig)    
              cla(app.r2u_pert_fig)    
              cla(app.d2u_pert_fig)   
              app.Overshoot_pert.Value=0;
              app.SettlingTime_pert.Value=0;
              app.u_max_pert.Value=0;
        end
        
        function sliderEnable(app,iscontroller)
            if iscontroller
                app.J_slider.Enable='on';
                app.kt_slider.Enable='on';
                app.ky_slider.Enable='on';
                app.Jpert.Value=app.J_p.nom;
                app.ktpert.Value=app.kt_p.nom;
                app.kypert.Value=app.ky_p.nom;
            else
                app.J_slider.Enable='off';
                app.kt_slider.Enable='off';
                app.ky_slider.Enable='off';
            end
        end
        
        function  slider_reset(app)
                app.J_slider.Value=0;
                app.kt_slider.Value=0;
                app.ky_slider.Value=0;
        end
        
        function  controllerType(app, controller)
            
               app.controllertypeEditField.Value=controller; 
               app.controllertypeEditField_2.Value=controller; 
               app.controllertypeEditField_3.Value=controller; 
               app.controllertypeEditField_4.Value=controller; 
               app.controllertypeEditField_5.Value=controller; 
               app.controllertypeEditField_6.Value=controller;
               app.controllertypeEditField_7.Value=controller; 
               app.controllertypeEditField_8.Value=controller; 
               app.controllertypeEditField_9.Value=controller; 
               app.controllertypeEditField_10.Value=controller; 
               app.controllertypeEditField_11.Value=controller; 
               app.controllertypeEditField_12.Value=controller; 
               app.controllertypeEditField_13.Value=controller; 
        end
        
        function  worstRS_pert_fig(app,K,worstpert)
            % worst perturbed system for robustness(worstpert is a struct with the max perturbations allowed,computed with 'robstab' function)
               
               
               G_pert=usubs(app.G,worstpert);
               
               %app.Jpert.Value=J_pert;
               %app.ktpert.Value=kt_pert;
               %app.kypert.Value=ky_pert;
               
            % perturbed transfer functions
               
               Lpert=K*G_pert(1,2);   % perturbed open loop transfer function from r to y
               Spert=(1+Lpert)^-1;      % perturbed sensibility function(tf from d to y)
               Sd2PESpert=-0.0005*G_pert(1,1)*Spert;  % perturbed tf from disturbance( 0.0005Nm amplitude step) to PES
               Tpert=1-Spert;             % perturbed complementary sensibility function(tf from r to y)
               Supert=K*Spert;     % perturbed control sensibility function(from r to u)
               Sudpert=0.0005*K*Spert*G_pert(1,1);  % perturbed transfer function 
                                             % from disturbance( 0.0005Nm amplitude step) to u
 
               cla(app.r2e_pert_fig)              
               [y,t]=step(Spert,app.Tfinal);
               plot(app.r2e_pert_fig,t,y) 
               grid(app.r2e_pert_fig, 'on')
               app.r2e_pert_fig.XLim = [0, t(end)];
                
               %Step_pert=stepinfo(Tpert,'SettlingTimeThreshold',0.05);
               app.SettlingTime_pert.Value=Inf;
               app.Overshoot_pert.Value=100*abs(min(y));
               
               cla(app.d2e_pert_fig)              
               [y,t]=step(Sd2PESpert,app.Tfinal);
               plot(app.d2e_pert_fig,t,y) 
               grid(app.d2e_pert_fig, 'on')
               app.d2e_pert_fig.XLim = [0, t(end)];
               
               cla(app.r2u_pert_fig)              
               [y,t]=step(Supert,app.Tfinal);
               plot(app.r2u_pert_fig,t,y) 
               grid(app.r2u_pert_fig, 'on')
               app.r2u_pert_fig.XLim = [0, t(end)];
               
               app.u_max_pert.Value=max(abs(y));
               
               cla(app.d2u_pert_fig)              
               [y,t]=step(Sudpert,app.Tfinal);
               plot(app.d2u_pert_fig,t,y) 
               grid(app.d2u_pert_fig, 'on')
               app.d2u_pert_fig.XLim = [0, t(end)];
        end
        
        function worstRP_pert_fig(app,K,worstpert)
            % worst perturbed system for performance(worstpert is a struct with the max perturbations allowed,computed with 'robgain' function)
               
               
               G_pert=usubs(app.G,worstpert);
               
               %app.Jpert.Value=J_pert;
               %app.ktpert.Value=kt_pert;
               %app.kypert.Value=ky_pert;
               
            % perturbed transfer functions
               
               Lpert=K*G_pert(1,2);   % perturbed open loop transfer function from r to y
               Spert=(1+Lpert)^-1;      % perturbed sensibility function(tf from d to y)
               Sd2PESpert=-0.0005*G_pert(1,1)*Spert;  % perturbed tf from disturbance( 0.0005Nm amplitude step) to PES
               Tpert=1-Spert;             % perturbed complementary sensibility function(tf from r to y)
               Supert=K*Spert;     % perturbed control sensibility function(from r to u)
               Sudpert=0.0005*K*Spert*G_pert(1,1);  % perturbed transfer function 
                                             % from disturbance( 0.0005Nm amplitude step) to u
 
               cla(app.r2e_pert_fig)              
               [y,t]=step(Spert,app.Tfinal);
               plot(app.r2e_pert_fig,t,y) 
               grid(app.r2e_pert_fig, 'on')
               app.r2e_pert_fig.XLim = [0, t(end)];
                
               Step_pert=stepinfo(Tpert,'SettlingTimeThreshold',0.02);
               app.SettlingTime_pert.Value=Step_pert.SettlingTime;
               app.Overshoot_pert.Value=Step_pert.Overshoot;
               
               cla(app.d2e_pert_fig)              
               [y,t]=step(Sd2PESpert,app.Tfinal);
               plot(app.d2e_pert_fig,t,y) 
               grid(app.d2e_pert_fig, 'on')
               app.d2e_pert_fig.XLim = [0, t(end)];
               
               cla(app.r2u_pert_fig)              
               [y,t]=step(Supert,app.Tfinal);
               plot(app.r2u_pert_fig,t,y) 
               grid(app.r2u_pert_fig, 'on')
               app.r2u_pert_fig.XLim = [0, t(end)];
               
               app.u_max_pert.Value=max(abs(y));
               
               cla(app.d2u_pert_fig)              
               [y,t]=step(Sudpert,app.Tfinal);
               plot(app.d2u_pert_fig,t,y) 
               grid(app.d2u_pert_fig, 'on')
               app.d2u_pert_fig.XLim = [0, t(end)];
        end
        
        function worstButtonEnable(app,contr)
            if contr==4 || contr==5
                app.WorstRSperturbationButton.Enable='on';
                app.WorstRPperturbationButton.Enable='on';
            else
                app.WorstRSperturbationButton.Enable='off';
                app.WorstRPperturbationButton.Enable='off';
            end
        end
        
  end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            controlbuttonEnable(app, false)
            comparisonbuttonEnable(app, false,false)
            sliderEnable(app,false)
            app.is_enable_hinf=0;
            app.is_enable_mu=0;      
            app.WorstRSperturbationButton.Enable='off';
            app.WorstRPperturbationButton.Enable='off';  
            
            load init.mat
            
            %load Kcl.mat
            app.Kcl=Kcl;
            
            app.Kltr=Kltr;
            
            %load max_pert_mu_RS.mat
            app.worst_mu_RS=max_pert_mu_RS;
            
            %load max_pert_mu_RP.mat
            app.worst_mu_RP=max_pert_mu_RP;
            
            %load max_pert_inf_RS.mat
            app.worst_inf_RS=max_pert_mu_RS;
            
            %load max_pert_inf_RP.mat
            app.worst_inf_RP=max_pert_mu_RP;
            
            app.MaxSingValue_cl=SV_cl;
            
            app.MaxSingValue_lqg=SV_lqg;
            
            app.MaxSingValue_ltr=SV_ltr;
            
            %load SV_hinf.mat
            app.MaxSingValue_hinf=SV_hinf;
            
            %load SV_mu.mat
            app.MaxSingValue_mu=SV_mu;
            
            app.mu_RS_cl=muRS_cl;
            
            app.mu_RS_lqg=muRS_lqg;
            
            app.mu_RS_ltr=muRS_ltr;
            
            %load muRS_hinf.mat
            app.mu_RS_hinf=muRS_hinf;
            
            %load muRS_mu.mat
            app.mu_RS_mu=muRS_mu;
            
            app.mu_RP_cl=muRP_cl;
            
            app.mu_RP_lqg=muRP_lqg;
            
            app.mu_RP_ltr=muRP_ltr;
            
            %load muRP_hinf.mat
            app.mu_RP_hinf=muRP_hinf;
            
            %load muRP_mu.mat
            app.mu_RP_mu=muRP_mu;
        end

        % Button pushed function: GeneratePlantModelButton
        function GeneratePlantModelButtonPushed(app, event)
            % % Defining uncertain parameters
J=ureal('J',6.3857e-6,'Percentage',[-10,10]);          % arm inertia in kg/m^2
kt=ureal('kt',9.183e-2,'Percentage',[-10,10]);           % VCM torque constant in N*m/A
ky=ureal('ky',1.2,'Percentage',[-5,5]);                     % position measurement gain in V/track
w1=ureal('w1',2*pi*50,'Percentage',[-5,5]);              % pivot bearing resonance frequency in rad/s
w2=ureal('w2',2*pi*2200,'Percentage',[-12,12]);       % first torsional resonance frequency in rad/s
w3=ureal('w3',2*pi*6400,'Percentage',[-8,8]);           % second torsional resonance frequency in rad/s
w4=ureal('w4',2*pi*8800,'Percentage',[-15,15]);       % first sway resonance frequency in rad/s
psi1=ureal('psi1',0.05,'Percentage',[-5,5]);              % first resonance damping
psi2=ureal('psi2',0.024,'Percentage',[-8,8]);             % second resonance damping
psi3=ureal('psi3',0.129,'Percentage',[-10,10]);         % third resonance damping
psi4=ureal('psi4',0.173,'Percentage',[-10,10]);          % fourth resonance damping
% Defining fixed parameters
kb=9.183e-2;        % back e.m.f. constant in N*m/A
R=5.08e-2;           % arm lenght in m
kpa=10;                % amplifier gain in V/V
tpm=1e6;              % tracks per meter
Rcoil=8;                % coil resistance in Ohm
Rs=0.2;                 % sense resistance in the power amplifier feedback in Ohm
Lcoil=0.001;           % coil inductance in H
emax=12;              % saturated power amplifier voltage in V
RPM=7200;           % disk rotation rate in rev/min
tw=1e-6;                % track width in m
b1=0.006;              % first resonance coupling
b2=0;                    % first resonance coupling
b3=0.013;             % second resonance coupling
b4=-0.0018;          % second resonance coupling
b5=0.723;             % third resonance coupling
b6=-0.0015;          % third resonance coupling
b7=0.235;             % fourth resonance coupling
b8=-0.0263;          % fourth resonance coupling

Rc=Rcoil+Rs;      % VC static admittance in Ohm
tau=Lcoil/Rc;       % time constant of VC admittance transfer function in secs


% Defining the uncertain plant model G

Gvca=tf(Rc^-1,[tau,1]);    % Voice Coile admittance transfer function(from input voltage ec to output corrent ic)

A1=[0 w1; -w1 -2*psi1*w1];
B1=[0;w1];
C1=[b1 b2];
G1=uss(A1,B1,C1,0);       % First resonance mode

A2=[0 w2; -w2 -2*psi2*w2];
B2=[0;w2];
C2=[b3 b4];
G2=uss(A2,B2,C2,0);       % Second resonance mode

A3=[0 w3; -w3 -2*psi3*w3];
B3=[0;w3];
C3=[b5 b6];
G3=uss(A3,B3,C3,0);       % Third resonance mode

A4=[0 w4; -w4 -2*psi4*w4];
B4=[0;w4];
C4=[b7 b8];
G4=uss(A4,B4,C4,0);       % Fourth resonance mode

Rot_f_int=uss(0,1/J,1,0);          % First integrator in arm rotational dynamic
Rot_s_int=ss(0,R*tpm,1,0);       % Second integrator in arm rotational dynamic


systemnames= ' kpa  kb  Rs  Gvca  kt  G1  G2  G3  G4  Rot_f_int  Rot_s_int  ky';
inputvar= '[td;u]';
outputvar='[ky]';
input_to_kpa='[u-Rs]';
input_to_Gvca='[kpa-kb]';
input_to_Rs='[Gvca]';
input_to_kt='[Gvca]';
input_to_G1='[kt+td]';
input_to_G2='[kt+td]';
input_to_G3='[kt+td]';
input_to_G4='[kt+td]';
input_to_Rot_f_int='[G1+G2+G3+G4]';
input_to_kb='[Rot_f_int]';
input_to_Rot_s_int='[Rot_f_int]';
input_to_ky='[Rot_s_int]';
sysoutname = 'Gg';
cleanupsysic = 'yes';
sysic; 

app.G=Gg;
save_in_workspace(app,app.G,'G');  % save uncertain state space model G in the workspace

Gd=app.G(1,1);    % transfer function from td to y

Gu=app.G(1,2);    % transfer function from u to y


%bode(Gu,'--')   % bode plot from u to y

%bode(Gd,'--')   % bode plot from td to y

Gnom=app.G.Nominalvalue;      % nominal transfer matrix from [td;u] to y

Gdnom=Gd.Nominalvalue;   % nominal transfer function from td to y

Gunom=Gu.Nominalvalue;  % nominal transfer function from u to y


% Defining weighting function M,Wp,Wu

M=tf(1,[3.75e-9,1.2e-4,1]);   % ideal closed loop transfer function(no overshoot and settling time of 0.3ms)
%figure
%step(M)
Wn= 6e-4*tf([0.1,1],[0.01,1]);  % noise weighting function(noise is 0.6 mV at low frequencies and 60mV at high frequencies
%figure
%bodemag(Wn)
Wp1=1e-4*tf([1,8e4,1e8],[1,7e4,2.5e4]); % first performance weighting function(final choice)
Wp2=1e-4*tf([1,4e5,2.5e9],[1,3.9e5,6.25e5]); % second performance weighting function
Wp3=1e-4*tf([1,1.15e6,3.6e10],[1,1.05e6,9e6]); % third performance weighting function
%figure
%bodemag(1/Wp1,1/Wp2,1/Wp3)
%legend(' 1/|Wp1| ', ' 1/|Wp2|', ' 1/|Wp3| ')

Wu1=1e-6*tf([0.385,1,1],[1e-4,2e-3,1]);   % first control weighting function
Wu2=1e-6*tf([0.55,1,1],[1e-4,2.1e-3,1]);  % second control weighting function(final choice)
Wu3=3e-6*tf([4.05,1,1],[1e-4,2e-3,1]);   % third control weighting function


Wp=Wp1;
Wu=Wu2;

systemnames= 'Gg Wp Wu Wn M';
inputvar= '[r ; d ; n ; u]' ;
outputvar= '[ Wp ; Wu ;  r-Wn-Gg(1) ]' ;
input_to_Gg='[d;u]';
input_to_Wn='[n]';
input_to_M='[r]';
input_to_Wp='[Gg(1)-M]' ;
input_to_Wu='[u]' ;
sysoutname='Pp'; 
cleanupsysic='yes';
sysic;
 
app.P=Pp;
app.Pnom=app.P.Nominalvalue;
save_in_workspace(app,app.P,'P');  % save uncertain state space general plant P in the workspace

               w=logspace(0,8,500);
               dd=usample(app.G(1,2),30);
               cla(app.Gmag_fig)
               cla(app.Gphase_fig)
               hold(app.Gmag_fig,'on')
               hold(app.Gphase_fig,'on')
               for i=1:30
               [m,f]=bode(dd(:,:,i,1),w);
               hold on
               plot(app.Gmag_fig,w,20*log10(m(1,:)))               
               plot(app.Gphase_fig,w,f(1,:)) 
               end
               app.Gmag_fig.XScale='log';
               app.Gphase_fig.XScale='log';
               hold(app.Gmag_fig,'off')
               hold(app.Gphase_fig,'off')
               grid(app.Gmag_fig, 'on')
               grid(app.Gphase_fig, 'on')
               close

               
               cla(app.pzG_fig)
               [z,p,~] = zpkdata(Gunom,'v');
               hold(app.pzG_fig,'on')
               scatter(app.pzG_fig,real(p),imag(p),'x')
               hold on
               scatter(app.pzG_fig,real(z),imag(z),'o')
               hold(app.pzG_fig,'off')
               grid(app.pzG_fig, 'on')
               close
               
app.J_p=J;
app.kt_p=kt;
app.ky_p=ky;
                

controlbuttonEnable(app, true)   % enable controller design buttons

%app.J_slider.Limits= J.Percentage
        end

        % Button pushed function: ClassiccontrollerButton
        function ClassiccontrollerButtonPushed(app, event)
            % classical control design(loaded)
             save_in_workspace(app,app.Kcl,'Kcl');  % save classic controller in the workspace
             app.Kev=app.Kcl;
             figGenerate(app,app.Kcl)
             
             om=logspace(1,5,100);
             plot(app.NP_comp_fig,om,app.MaxSingValue_cl.Responsedata(1,:))
             app.NP_comp_fig.XScale='log';
             grid(app.NP_comp_fig, 'on')
             app.NP_comp_fig.XLim = [0, om(1,end)];
             
             plot(app.RS_comp_fig,om,app.mu_RS_cl.Responsedata(1,:))
             app.RS_comp_fig.XScale='log';
             grid(app.RS_comp_fig, 'on')
             app.RS_comp_fig.XLim = [0, om(1,end)];
             
             plot(app.RP_comp_fig,om,app.mu_RP_cl.Responsedata(1,:))
             app.RP_comp_fig.XScale='log';
               grid(app.RP_comp_fig, 'on')
               app.RP_comp_fig.XLim = [0, om(1,end)];
               
             clear_pertFig(app)
             iscontroller=1; % to enable sliders
             sliderEnable(app,iscontroller)
             slider_reset(app)
             controllerType(app, 'classic')
             app.controllerNumber=1;
        end

        % Button pushed function: LQGcontrollerButton
        function LQGcontrollerButtonPushed(app, event)
            % design LQG controller

Gu=app.G(1,2);            
Gunom=Gu.Nominalvalue;            
[A,~,~,~]=ssdata(Gunom);
n=length(A);
Q=eye(n);
%Kr=lqr(A,B,Q,1);  % computes optimal feedback regulator

W=eye(n);
V=1;
% Kf=Kalman(Gunom,W,V,0)  % computes optimal state estimator(kalman filter)

Qweight=blkdiag(Q,1);
Qnoise=blkdiag(W,V);
app.Klqg=lqg(Gunom,Qweight,Qnoise,1,'1dof');   % computes LQG controller adding integral action to force reference tracking
save_in_workspace(app,app.Klqg,'Klqg');  % save LQG controller in the workspace
app.Kev=app.Klqg;
figGenerate(app,app.Klqg)

om=logspace(1,5,100);

plot(app.NP_comp_fig,om,app.MaxSingValue_lqg.Responsedata(1,:))
app.NP_comp_fig.XScale='log';
grid(app.NP_comp_fig, 'on')
app.NP_comp_fig.XLim = [0, om(1,end)];

plot(app.RS_comp_fig,om,app.mu_RS_lqg.Responsedata(1,:))
app.RS_comp_fig.XScale='log';
grid(app.RS_comp_fig, 'on')
app.RS_comp_fig.XLim = [0, om(1,end)];
             
plot(app.RP_comp_fig,om,app.mu_RP_lqg.Responsedata(1,:))
app.RP_comp_fig.XScale='log';
               grid(app.RP_comp_fig, 'on')
               app.RP_comp_fig.XLim = [0, om(1,end)];
               
clear_pertFig(app)
iscontroller=1; % to enable sliders
sliderEnable(app,iscontroller)
slider_reset(app)
controllerType(app, 'LQG')
app.controllerNumber=2;

        end

        % Button pushed function: HinfinitecontrollerButton
        function HinfinitecontrollerButtonPushed(app, event)
            % design of Hinf controller           
            
nmeas = 1;
ncon = 1;
gmin = 0;
gmax = 10;
tol = 0.001;

[app.Kinf,~,GAM] = hinfsyn(app.Pnom,nmeas,ncon,'GMIN',gmin,'GMAX',gmax,'TOLGAM',tol);

% taking a value of the infinite norm 10% higher than the minumim(GAM) in
% order to reduce the complexity of the controller(sub-optimal Hinf controller)
gmin=1.1*GAM;
[app.Kinf,~,~] = hinfsyn(app.Pnom,nmeas,ncon,'GMIN',gmin,'GMAX',gmax,'TOLGAM',tol);
save_in_workspace(app,app.Kinf,'Kinf');  % save Hinf controller in the workspace
app.Kev=app.Kinf;
figGenerate(app,app.Kinf)

om=logspace(1,5,100);
plot(app.NP_comp_fig,om,app.MaxSingValue_hinf.Responsedata(1,:))
app.NP_comp_fig.XScale='log';
grid(app.NP_comp_fig, 'on')
app.NP_comp_fig.XLim = [0, om(1,end)];

plot(app.RS_comp_fig,om,app.mu_RS_hinf.Responsedata(1,:))
app.RS_comp_fig.XScale='log';
grid(app.RS_comp_fig, 'on')
app.RS_comp_fig.XLim = [0, om(1,end)];

plot(app.RP_comp_fig,om,app.mu_RP_hinf.Responsedata(1,:))
app.RP_comp_fig.XScale='log';
               grid(app.RP_comp_fig, 'on')
               app.RP_comp_fig.XLim = [0, om(1,end)];
               
clear_pertFig(app)
app.is_enable_hinf=1;
comparisonbuttonEnable(app, app.is_enable_hinf,app.is_enable_mu) 
iscontroller=1; % to enable sliders
sliderEnable(app,iscontroller)
slider_reset(app)
controllerType(app, 'H infinite')
app.controllerNumber=4;
worstButtonEnable(app,app.controllerNumber)
        end

        % Button pushed function: mucontrollerButton
        function mucontrollerButtonPushed(app, event)
            % design of mu controller
% taking into account in plant model only uncertainties in kt,ky,J(rigid body uncertainties)
% fixing all other parameters
app.Pmu=usubs(app.P, 'w1','Nom', 'w2','Nom', 'w3','Nom', 'w4','Nom', 'psi1', 'Nom', 'psi2', 'Nom',  'psi3', 'Nom',  'psi4', 'Nom');  % uncertain plant used for mu synthesis
nmeas = 1;
ncon = 1;
om=logspace(2,5,100);
opt=dkitopt('FrequencyVector',om, 'DisplayWhileAutoIter','off','NumberOfAutoIterations',3) ;
[app.Kmu,~,~,~]=dksyn(app.Pmu,nmeas,ncon,opt);
save_in_workspace(app,app.Kmu,'Kmu');  % save mu controller in the workspace
app.Kev=app.Kmu;
figGenerate(app,app.Kmu)

om=logspace(1,5,100);
plot(app.NP_comp_fig,om,app.MaxSingValue_mu.Responsedata(1,:))
app.NP_comp_fig.XScale='log';
grid(app.NP_comp_fig, 'on')
app.NP_comp_fig.XLim = [0, om(1,end)];

plot(app.RS_comp_fig,om,app.mu_RS_mu.Responsedata(1,:))
app.RS_comp_fig.XScale='log';
             grid(app.RS_comp_fig, 'on')
             app.RS_comp_fig.XLim = [0, om(1,end)];

plot(app.RP_comp_fig,om,app.mu_RP_mu.Responsedata(1,:))
app.RP_comp_fig.XScale='log';
               grid(app.RP_comp_fig, 'on')
               app.RP_comp_fig.XLim = [0, om(1,end)];
               
clear_pertFig(app)
app.is_enable_mu=1;
comparisonbuttonEnable(app, app.is_enable_hinf,app.is_enable_mu)
iscontroller=1; % to enable sliders
sliderEnable(app,iscontroller)
slider_reset(app)
controllerType(app, 'mu')
app.controllerNumber=5;
worstButtonEnable(app,app.controllerNumber)
        end

        % Button pushed function: RScomparisonButton
        function RScomparisonButtonPushed(app, event)
            %[app.mu_RS_cl]=RobustStability(app,app.P,app.Kcl);            
            %save_in_workspace(app,app.mu_RS_cl,'muRS_cl');
            
            
            %[app.mu_RS_lqg]=RobustStability(app,app.P,app.Klqg);
            %save_in_workspace(app,app.mu_RS_lqg,'muRS_lqg');
            
            
            %[app.mu_RS_hinf]=RobustStability(app,app.P,app.Kinf);                  %% taking these variables directly from passed simulations 
            %save_in_workspace(app,app.mu_RS_hinf,'muRS_hinf');                       because they are long to compute


            %[app.mu_RS_mu]=RobustStability(app,app.P,app.Kmu);
            %save_in_workspace(app,app.mu_RS_mu,'muRS_mu');
            
               om=logspace(1,5,100);
            
               cla(app.RS_comp_fig)
               hold(app.RS_comp_fig,'on')   
               plot(app.RS_comp_fig,om,app.mu_RS_cl.Responsedata(1,:))
               plot(app.RS_comp_fig,om,app.mu_RS_lqg.Responsedata(1,:))
               plot(app.RS_comp_fig,om,app.mu_RS_ltr.Responsedata(1,:))
               plot(app.RS_comp_fig,om,app.mu_RS_hinf.Responsedata(1,:))
               plot(app.RS_comp_fig,om,app.mu_RS_mu.Responsedata(1,:))
               app.RS_comp_fig.XScale='log';
               grid(app.RS_comp_fig, 'on')
               app.RS_comp_fig.XLim = [0, om(1,end)];
               legend(app.RS_comp_fig,'classic controller','LQG controller','LTR controller','H inf controller','mu controller','Location','northwest')
               hold(app.RS_comp_fig,'off')
        end

        % Button pushed function: RPcomparisonButton
        function RPcomparisonButtonPushed(app, event)
            %[app.mu_RP_cl]=RobustPerformance(app,app.P,app.Kcl);
            %save_in_workspace(app,app.mu_RP_cl,'muRP_cl');
            
            %[app.mu_RP_lqg]=RobustPerformance(app,app.P,app.Klqg);                        
            %save_in_workspace(app,app.mu_RP_lqg,'muRP_lqg');
            
            %[app.mu_RP_hinf]=RobustPerformance(app,app.P,app.Kinf);                  %% taking these variables directly from passed simulations       
            %save_in_workspace(app,app.mu_RP_hinf,'muRP_hinf');                          because they are long to compute
                                   
            %[app.mu_RP_mu]=RobustPerformance(app,app.P,app.Kmu);                        
            %save_in_workspace(app,app.mu_RP_mu,'muRP_mu');
            
               om=logspace(1,5,100);  
              
               cla(app.RP_comp_fig)
               hold(app.RP_comp_fig,'on')   
               plot(app.RP_comp_fig,om,app.mu_RP_cl.Responsedata(1,:))
               plot(app.RP_comp_fig,om,app.mu_RP_lqg.Responsedata(1,:))
               plot(app.RP_comp_fig,om,app.mu_RP_ltr.Responsedata(1,:))
               plot(app.RP_comp_fig,om,app.mu_RP_hinf.Responsedata(1,:))
               plot(app.RP_comp_fig,om,app.mu_RP_mu.Responsedata(1,:))
               app.RP_comp_fig.XScale='log';
               grid(app.RP_comp_fig, 'on')
               app.RP_comp_fig.XLim = [0, om(1,end)];
               legend(app.RP_comp_fig,'classic controller','LQG controller','LTR controller','H inf controller','mu controller')
               hold(app.RP_comp_fig,'off')
        end

        % Button pushed function: NPcomparisonButton
        function NPcomparisonButtonPushed(app, event)
            %[app.MaxSingValue_cl]=NominalPerformance(app,app.P,app.Kcl);
            %save_in_workspace(app,app.MaxSingValue_cl,'SV_cl');
            
            %[app.MaxSingValue_lqg]=NominalPerformance(app,app.P,app.Klqg);               %% taking these variables directly from passed simulations  
            %save_in_workspace(app,app.MaxSingValue_lqg,'SV_lqg');                           because they are long to compute
            
            %[app.MaxSingValue_hinf]=NominalPerformance(app,app.P,app.Kinf);
            %save_in_workspace(app,app.MaxSingValue_hinf,'SV_hinf');
            
            %[app.MaxSingValue_mu]=NominalPerformance(app,app.P,app.Kmu);
            %save_in_workspace(app,app.MaxSingValue_mu,'SV_mu');
            
               om=logspace(1,5,100);
               
               cla(app.NP_comp_fig)
               hold(app.NP_comp_fig,'on')                  
               plot(app.NP_comp_fig,om,app.MaxSingValue_cl.Responsedata(1,:))
               plot(app.NP_comp_fig,om,app.MaxSingValue_lqg.Responsedata(1,:))  
               plot(app.NP_comp_fig,om,app.MaxSingValue_ltr.Responsedata(1,:))  
               plot(app.NP_comp_fig,om,app.MaxSingValue_hinf.Responsedata(1,:))                              
               plot(app.NP_comp_fig,om,app.MaxSingValue_mu.Responsedata(1,:))
               app.NP_comp_fig.XScale='log';
               grid(app.NP_comp_fig, 'on')
               app.NP_comp_fig.XLim = [0, om(1,end)];
               legend(app.NP_comp_fig,'classic controller','LQG controller','LTR controller','H inf controller','mu controller')
               hold(app.NP_comp_fig,'off')
        end

        % Value changed function: J_slider
        function J_sliderValueChanged(app, event)
            app.rel_pert_J= app.J_slider.Value;                           
            pert_fig(app,app.Kev)
            app.Jpert.Value=app.J_p.nom*(1+app.rel_pert_J*0.1);  % J perturbed value;    
        end

        % Value changed function: kt_slider
        function kt_sliderValueChanged(app, event)
            app.rel_pert_kt = app.kt_slider.Value;            
            pert_fig(app,app.Kev)
            app.ktpert.Value=app.kt_p.nom*(1+app.rel_pert_kt*0.1);  % kt perturbed value
               
        end

        % Value changed function: ky_slider
        function ky_sliderValueChanged(app, event)
            app.rel_pert_ky = app.ky_slider.Value;            
            pert_fig(app,app.Kev)
            app.kypert.Value=app.ky_p.nom*(1+app.rel_pert_ky*0.05);  % ky perturbed value
        end

        % Button pushed function: WorstRSperturbationButton
        function WorstRSperturbationButtonPushed(app, event)
          app.Jpert.Value=app.worst_mu_RS.J;                    
          app.ktpert.Value=app.worst_mu_RS.kt;
          app.kypert.Value=app.worst_mu_RS.ky;
          
          if app.controllerNumber==5
          worstRS_pert_fig(app,app.Kev,app.worst_mu_RS)
          elseif app.controllerNumber==4
              worstRS_pert_fig(app,app.Kev,app.worst_inf_RS)
          
          end
        end

        % Button pushed function: WorstRPperturbationButton
        function WorstRPperturbationButtonPushed(app, event)
             app.Jpert.Value=app.worst_mu_RP.J;                    
             app.ktpert.Value=app.worst_mu_RP.kt;
             app.kypert.Value=app.worst_mu_RP.ky;
            
            if app.controllerNumber==5
             worstRP_pert_fig(app,app.Kev,app.worst_mu_RP)
            elseif app.controllerNumber==4
              worstRP_pert_fig(app,app.Kev,app.worst_inf_RP)
             
           end
        end

        % Button pushed function: LTRcontrollerButton
        function LTRcontrollerButtonPushed(app, event)
            % LTR design
            
            %[A,B,C,~]=ssdata(app.G.nom(1,2));
            %w=logspace(2,5,500);
            %Xi = B*B'; Th = 200;           % Assign noise weighting
            %q  = 1e5; %[1 1e6 1e10 1e16];        % Assign recovery gain for Q
            %Q = C'*C; R = 200;             % Assign initial control wt. 
            %XiTh = diagmx(Xi,Th);           % Put Xi & Th into compact form
            %[Kf] = lqrc(A',C',XiTh);     % Solve Kalman filter gain
            %Kf = Kf';                       % using duality with LQR 
            %[app.Kltr] = ltrsyn(app.G.nom(1,2),Kf,Q,R,q,w,'OUTPUT'); 

save_in_workspace(app,app.Kltr,'Kltr');  % save LTR controller in the workspace
app.Kev=app.Kltr;
figGenerate(app,app.Kltr)

om=logspace(1,5,100);
plot(app.NP_comp_fig,om,app.MaxSingValue_ltr.Responsedata(1,:))
app.NP_comp_fig.XScale='log';
grid(app.NP_comp_fig, 'on')
app.NP_comp_fig.XLim = [0, om(1,end)];

plot(app.RS_comp_fig,om,app.mu_RS_ltr.Responsedata(1,:))
app.RS_comp_fig.XScale='log';
grid(app.RS_comp_fig, 'on')
app.RS_comp_fig.XLim = [0, om(1,end)];

plot(app.RP_comp_fig,om,app.mu_RP_ltr.Responsedata(1,:))
app.RP_comp_fig.XScale='log';
               grid(app.RP_comp_fig, 'on')
               app.RP_comp_fig.XLim = [0, om(1,end)];
               
clear_pertFig(app)
iscontroller=1; % to enable sliders
sliderEnable(app,iscontroller)
slider_reset(app)
controllerType(app, 'LTR')
app.controllerNumber=3;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HDDservosystemUIFigure = uifigure;
            app.HDDservosystemUIFigure.Name = 'HDD servo system';
            app.EditField = uieditfield(app.HDDservosystemUIFigure, 'text');
            app.EditField.Value = 'Hard Disk Drive servo system design';
            app.GeneratePlantModelButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.GeneratePlantModelButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlantModelButtonPushed, true);
            app.GeneratePlantModelButton.Text = 'Generate Plant Model';
            app.ClassiccontrollerButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.ClassiccontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @ClassiccontrollerButtonPushed, true);
            app.ClassiccontrollerButton.Text = 'Classic controller';
            app.LQGcontrollerButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.LQGcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @LQGcontrollerButtonPushed, true);
            app.LQGcontrollerButton.Text = 'LQG controller';
            app.HinfinitecontrollerButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.HinfinitecontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @HinfinitecontrollerButtonPushed, true);
            app.HinfinitecontrollerButton.Text = 'Hinfinite controller';
            app.mucontrollerButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.mucontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @mucontrollerButtonPushed, true);
            app.mucontrollerButton.Text = 'mu controller';
            app.Figures = uitabgroup(app.HDDservosystemUIFigure);
            app.Plant = uitab(app.Figures);
            app.Plant.Title = 'Plant Gu';
            app.Gmag_fig = uiaxes(app.Plant);
            title(app.Gmag_fig, 'plant Bode magnitude plot')
            xlabel(app.Gmag_fig, 'omega(rad/s)')
            ylabel(app.Gmag_fig, 'magnitude(dB)')
            app.Gphase_fig = uiaxes(app.Plant);
            title(app.Gphase_fig, 'plant Bode phase plot')
            xlabel(app.Gphase_fig, 'omega(rad/s)')
            ylabel(app.Gphase_fig, 'phase(°)')
            app.pzGu = uitab(app.Figures);
            app.pzGu.Title = 'pz Gu';
            app.pzG_fig = uiaxes(app.pzGu);
            title(app.pzG_fig, 'plant zeros and poles')
            xlabel(app.pzG_fig, 'Re')
            ylabel(app.pzG_fig, 'Im')
            app.r2e_nom = uitab(app.Figures);
            app.r2e_nom.Title = 'r2e nom';
            app.r2e_fig = uiaxes(app.r2e_nom);
            title(app.r2e_fig, 'r2e nominal')
            xlabel(app.r2e_fig, 'time(sec)')
            ylabel(app.r2e_fig, 'Position Error Signal(PES)')
            app.SettlingTime5EditFieldLabel = uilabel(app.r2e_nom);
            app.SettlingTime5EditFieldLabel.Text = 'Settling Time(5%)';
            app.SettlingTime = uieditfield(app.r2e_nom, 'numeric');
            app.SettlingTime.Editable = 'off';
            app.OvershootEditFieldLabel = uilabel(app.r2e_nom);
            app.OvershootEditFieldLabel.Text = 'Overshoot(%)';
            app.Overshoot = uieditfield(app.r2e_nom, 'numeric');
            app.Overshoot.Editable = 'off';
            app.controllertypeEditFieldLabel = uilabel(app.r2e_nom);
            app.controllertypeEditFieldLabel.Text = 'controller type';
            app.controllertypeEditField = uieditfield(app.r2e_nom, 'text');
            app.controllertypeEditField.Editable = 'off';
            app.d2e_nom = uitab(app.Figures);
            app.d2e_nom.Title = 'd2e nom';
            app.d2e_fig = uiaxes(app.d2e_nom);
            title(app.d2e_fig, 'd2e nominal')
            xlabel(app.d2e_fig, 'time(sec)')
            ylabel(app.d2e_fig, 'Position Error Signal(PES)')
            app.controllertypeEditField_2Label = uilabel(app.d2e_nom);
            app.controllertypeEditField_2Label.Text = 'controller type';
            app.controllertypeEditField_2 = uieditfield(app.d2e_nom, 'text');
            app.controllertypeEditField_2.Editable = 'off';
            app.r2u_nom = uitab(app.Figures);
            app.r2u_nom.Title = 'r2u nom';
            app.r2u_fig = uiaxes(app.r2u_nom);
            title(app.r2u_fig, 'r2u nominal')
            xlabel(app.r2u_fig, 'time(sec)')
            ylabel(app.r2u_fig, 'Control(V)')
            app.u_maxEditFieldLabel = uilabel(app.r2u_nom);
            app.u_maxEditFieldLabel.Text = 'u_max';
            app.u_max = uieditfield(app.r2u_nom, 'numeric');
            app.u_max.Editable = 'off';
            app.controllertypeEditField_3Label = uilabel(app.r2u_nom);
            app.controllertypeEditField_3Label.Text = 'controller type';
            app.controllertypeEditField_3 = uieditfield(app.r2u_nom, 'text');
            app.controllertypeEditField_3.Editable = 'off';
            app.d2u_nom = uitab(app.Figures);
            app.d2u_nom.Title = 'd2u nom';
            app.d2u_fig = uiaxes(app.d2u_nom);
            title(app.d2u_fig, 'd2u nominal')
            xlabel(app.d2u_fig, 'time(sec)')
            ylabel(app.d2u_fig, 'Control(V)')
            app.controllertypeEditField_4Label = uilabel(app.d2u_nom);
            app.controllertypeEditField_4Label.Text = 'controller type';
            app.controllertypeEditField_4 = uieditfield(app.d2u_nom, 'text');
            app.controllertypeEditField_4.Editable = 'off';
            app.r2e_pert = uitab(app.Figures);
            app.r2e_pert.Title = 'r2e pert';
            app.r2e_pert_fig = uiaxes(app.r2e_pert);
            title(app.r2e_pert_fig, 'r2e perturbations')
            xlabel(app.r2e_pert_fig, 'time(sec)')
            ylabel(app.r2e_pert_fig, 'Position Error Signal(PES)')
            app.Overshoot_pertLabel = uilabel(app.r2e_pert);
            app.Overshoot_pertLabel.Text = 'Overshoot_pert(%)';
            app.Overshoot_pert = uieditfield(app.r2e_pert, 'numeric');
            app.Overshoot_pert.Editable = 'off';
            app.SettlingTime_pert5Label = uilabel(app.r2e_pert);
            app.SettlingTime_pert5Label.Text = 'Settling Time_pert(5%)';
            app.SettlingTime_pert = uieditfield(app.r2e_pert, 'numeric');
            app.SettlingTime_pert.Editable = 'off';
            app.controllertypeEditField_5Label = uilabel(app.r2e_pert);
            app.controllertypeEditField_5Label.Text = 'controller type';
            app.controllertypeEditField_5 = uieditfield(app.r2e_pert, 'text');
            app.controllertypeEditField_5.Editable = 'off';
            app.d2e_pert = uitab(app.Figures);
            app.d2e_pert.Title = 'd2e pert';
            app.d2e_pert_fig = uiaxes(app.d2e_pert);
            title(app.d2e_pert_fig, 'd2e perturbatios')
            xlabel(app.d2e_pert_fig, 'time(sec)')
            ylabel(app.d2e_pert_fig, 'Position Error Signal(PES)')
            app.controllertypeEditField_6Label = uilabel(app.d2e_pert);
            app.controllertypeEditField_6Label.Text = 'controller type';
            app.controllertypeEditField_6 = uieditfield(app.d2e_pert, 'text');
            app.controllertypeEditField_6.Editable = 'off';
            app.r2u_pert = uitab(app.Figures);
            app.r2u_pert.Title = 'r2u pert';
            app.r2u_pert_fig = uiaxes(app.r2u_pert);
            title(app.r2u_pert_fig, 'r2u perturbations')
            xlabel(app.r2u_pert_fig, 'time(sec)')
            ylabel(app.r2u_pert_fig, 'Control(V)')
            app.u_max_pertLabel = uilabel(app.r2u_pert);
            app.u_max_pertLabel.Text = 'u_max_pert';
            app.u_max_pert = uieditfield(app.r2u_pert, 'numeric');
            app.u_max_pert.Editable = 'off';
            app.controllertypeEditField_7Label = uilabel(app.r2u_pert);
            app.controllertypeEditField_7Label.Text = 'controller type';
            app.controllertypeEditField_7 = uieditfield(app.r2u_pert, 'text');
            app.controllertypeEditField_7.Editable = 'off';
            app.d2u_pert = uitab(app.Figures);
            app.d2u_pert.Title = 'd2u pert';
            app.d2u_pert_fig = uiaxes(app.d2u_pert);
            title(app.d2u_pert_fig, 'd2u perturbations')
            xlabel(app.d2u_pert_fig, 'time(sec)')
            ylabel(app.d2u_pert_fig, 'Control(V)')
            app.controllertypeEditField_8Label = uilabel(app.d2u_pert);
            app.controllertypeEditField_8Label.Text = 'controller type';
            app.controllertypeEditField_8 = uieditfield(app.d2u_pert, 'text');
            app.controllertypeEditField_8.Editable = 'off';
            app.OL_bode = uitab(app.Figures);
            app.OL_bode.Title = 'OL_bode';
            app.OL_bode_fig = uiaxes(app.OL_bode);
            title(app.OL_bode_fig, 'Open Loop Bode magnitude plot')
            xlabel(app.OL_bode_fig, 'omega(rad/s)')
            ylabel(app.OL_bode_fig, 'Magnotude(dB)')
            app.OL_phase_fig = uiaxes(app.OL_bode);
            title(app.OL_phase_fig, 'Open Loop Bode phase plot')
            xlabel(app.OL_phase_fig, 'omega(rad/s)')
            ylabel(app.OL_phase_fig, 'phase(°)')
            app.GainMargin = uieditfield(app.OL_bode, 'numeric');
            app.GainMargin.Editable = 'off';
            app.PhaseMargin = uieditfield(app.OL_bode, 'numeric');
            app.PhaseMargin.Editable = 'off';
            app.gainat100HzdBLabel = uilabel(app.OL_bode);
            app.gainat100HzdBLabel.Text = 'gain at 100Hz(dB)';
            app.gainat100Hz = uieditfield(app.OL_bode, 'numeric');
            app.gainat100Hz.Editable = 'off';
            app.controllertypeEditField_9Label = uilabel(app.OL_bode);
            app.controllertypeEditField_9Label.Text = 'controller type';
            app.controllertypeEditField_9 = uieditfield(app.OL_bode, 'text');
            app.controllertypeEditField_9.Editable = 'off';
            app.GMdBLabel = uilabel(app.OL_bode);
            app.GMdBLabel.Text = 'GM(dB)';
            app.PMLabel = uilabel(app.OL_bode);
            app.PMLabel.Text = 'PM(°)';
            app.CL_bode = uitab(app.Figures);
            app.CL_bode.Title = 'CL_bode';
            app.CL_bode_fig = uiaxes(app.CL_bode);
            title(app.CL_bode_fig, 'Closed Loop Bode magnitude plot')
            xlabel(app.CL_bode_fig, 'omega(rad/s)')
            ylabel(app.CL_bode_fig, 'Magnotude(dB)')
            app.CL_phase_fig = uiaxes(app.CL_bode);
            title(app.CL_phase_fig, 'Closed Loop Bode phase plot')
            xlabel(app.CL_phase_fig, 'omega(rad/s)')
            ylabel(app.CL_phase_fig, 'phase(°)')
            app.CLBandwidthEditFieldLabel = uilabel(app.CL_bode);
            app.CLBandwidthEditFieldLabel.Text = 'CL Bandwidth';
            app.CLBandwidth = uieditfield(app.CL_bode, 'numeric');
            app.CLBandwidth.Editable = 'off';
            app.CLpeakEditFieldLabel = uilabel(app.CL_bode);
            app.CLpeakEditFieldLabel.Text = 'CL peak';
            app.CLpeak = uieditfield(app.CL_bode, 'numeric');
            app.CLpeak.Editable = 'off';
            app.controllertypeEditField_10Label = uilabel(app.CL_bode);
            app.controllertypeEditField_10Label.Text = 'controller type';
            app.controllertypeEditField_10 = uieditfield(app.CL_bode, 'text');
            app.controllertypeEditField_10.Editable = 'off';
            app.Controller_bodeplot = uitab(app.Figures);
            app.Controller_bodeplot.Title = 'K_bode';
            app.K_bode_fig = uiaxes(app.Controller_bodeplot);
            title(app.K_bode_fig, 'Controller Bode magnitude plot ')
            xlabel(app.K_bode_fig, 'omega(rad/s)')
            ylabel(app.K_bode_fig, 'Magnotude(dB)')
            app.K_phase_fig = uiaxes(app.Controller_bodeplot);
            title(app.K_phase_fig, 'Controller Bode phase plot ')
            xlabel(app.K_phase_fig, 'omega(rad/s)')
            ylabel(app.K_phase_fig, 'phase(°)')
            app.controllertypeEditField_11Label = uilabel(app.Controller_bodeplot);
            app.controllertypeEditField_11Label.Text = 'controller type';
            app.controllertypeEditField_11 = uieditfield(app.Controller_bodeplot, 'text');
            app.controllertypeEditField_11.Editable = 'off';
            app.K_pz = uitab(app.Figures);
            app.K_pz.Title = 'K_pz';
            app.K_pz_fig = uiaxes(app.K_pz);
            title(app.K_pz_fig, 'Poles and zeros of the controller')
            xlabel(app.K_pz_fig, 'Re')
            ylabel(app.K_pz_fig, 'Im')
            app.controllertypeEditField_12Label = uilabel(app.K_pz);
            app.controllertypeEditField_12Label.Text = 'controller type';
            app.controllertypeEditField_12 = uieditfield(app.K_pz, 'text');
            app.controllertypeEditField_12.Editable = 'off';
            app.K_step = uitab(app.Figures);
            app.K_step.Title = 'K_step';
            app.K_step_fig = uiaxes(app.K_step);
            title(app.K_step_fig, 'controller step response')
            xlabel(app.K_step_fig, 'time(sec)')
            ylabel(app.K_step_fig, 'K response')
            app.controllertypeEditField_13Label = uilabel(app.K_step);
            app.controllertypeEditField_13Label.Text = 'controller type';
            app.controllertypeEditField_13 = uieditfield(app.K_step, 'text');
            app.controllertypeEditField_13.Editable = 'off';
            app.NP = uitab(app.Figures);
            app.NP.Title = 'NP';
            app.NP_comp_fig = uiaxes(app.NP);
            title(app.NP_comp_fig, 'Nominal Performance')
            xlabel(app.NP_comp_fig, 'omega(rad/s)')
            ylabel(app.NP_comp_fig, 'Max singular value of N')
            app.RS = uitab(app.Figures);
            app.RS.Title = 'RS';
            app.RS_comp_fig = uiaxes(app.RS);
            title(app.RS_comp_fig, 'Robust Stability')
            xlabel(app.RS_comp_fig, 'omega(rad/s)')
            ylabel(app.RS_comp_fig, 'structured singular value of M')
            app.RP = uitab(app.Figures);
            app.RP.Title = 'RP';
            app.RP_comp_fig = uiaxes(app.RP);
            title(app.RP_comp_fig, 'Robust Perfomance')
            xlabel(app.RP_comp_fig, 'omega(rad/s)')
            ylabel(app.RP_comp_fig, 'structured singular valure for RP')
            app.RScomparisonButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.RScomparisonButton.ButtonPushedFcn = createCallbackFcn(app, @RScomparisonButtonPushed, true);
            app.RScomparisonButton.Text = 'RS comparison';
            app.RPcomparisonButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.RPcomparisonButton.ButtonPushedFcn = createCallbackFcn(app, @RPcomparisonButtonPushed, true);
            app.RPcomparisonButton.Text = 'RP comparison';
            app.NPcomparisonButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.NPcomparisonButton.ButtonPushedFcn = createCallbackFcn(app, @NPcomparisonButtonPushed, true);
            app.NPcomparisonButton.Text = 'NP comparison';
            app.JKgm2Label = uilabel(app.HDDservosystemUIFigure);
            app.JKgm2Label.Text = 'J [Kg/m^2]';
            app.J_slider = uislider(app.HDDservosystemUIFigure);
            app.J_slider.Limits = [-1 1];
            app.J_slider.ValueChangedFcn = createCallbackFcn(app, @J_sliderValueChanged, true);
            app.ktNmALabel = uilabel(app.HDDservosystemUIFigure);
            app.ktNmALabel.Text = 'kt  [N*m/A]';
            app.kt_slider = uislider(app.HDDservosystemUIFigure);
            app.kt_slider.Limits = [-1 1];
            app.kt_slider.ValueChangedFcn = createCallbackFcn(app, @kt_sliderValueChanged, true);
            app.kyVtrackLabel = uilabel(app.HDDservosystemUIFigure);
            app.kyVtrackLabel.Text = 'ky [V/track]';
            app.ky_slider = uislider(app.HDDservosystemUIFigure);
            app.ky_slider.Limits = [-1 1];
            app.ky_slider.ValueChangedFcn = createCallbackFcn(app, @ky_sliderValueChanged, true);
            app.Jpert = uieditfield(app.HDDservosystemUIFigure, 'numeric');
            app.Jpert.Editable = 'off';
            app.ktpert = uieditfield(app.HDDservosystemUIFigure, 'numeric');
            app.ktpert.Editable = 'off';
            app.kypert = uieditfield(app.HDDservosystemUIFigure, 'numeric');
            app.kypert.Editable = 'off';
            app.WorstRSperturbationButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.WorstRSperturbationButton.ButtonPushedFcn = createCallbackFcn(app, @WorstRSperturbationButtonPushed, true);
            app.WorstRSperturbationButton.Text = 'Worst RS perturbation';
            app.WorstRPperturbationButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.WorstRPperturbationButton.ButtonPushedFcn = createCallbackFcn(app, @WorstRPperturbationButtonPushed, true);
            app.WorstRPperturbationButton.Text = 'Worst RP perturbation';
            app.LTRcontrollerButton = uibutton(app.HDDservosystemUIFigure, 'push');
            app.LTRcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @LTRcontrollerButtonPushed, true);
            app.LTRcontrollerButton.Text = 'LTR controller';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HDD_controllerDesign

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HDDservosystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HDDservosystemUIFigure)
        end
    end
end